classdef oscillograph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GridLayout     matlab.ui.container.GridLayout
        LeftPanel      matlab.ui.container.Panel
        Knob           matlab.ui.control.Knob
        Label_4        matlab.ui.control.Label
        Button_6       matlab.ui.control.Button
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        EditField_4    matlab.ui.control.NumericEditField
        HzButton       matlab.ui.control.ToggleButton
        HzButton_2     matlab.ui.control.ToggleButton
        HzButton_3     matlab.ui.control.ToggleButton
        Button_5       matlab.ui.control.Button
        Button_4       matlab.ui.control.StateButton
        Slider_3       matlab.ui.control.Slider
        Label_3        matlab.ui.control.Label
        Slider_2       matlab.ui.control.Slider
        Label_2        matlab.ui.control.Label
        Slider         matlab.ui.control.Slider
        Label          matlab.ui.control.Label
        EditField_3    matlab.ui.control.NumericEditField
        EditField_2    matlab.ui.control.NumericEditField
        EditField      matlab.ui.control.NumericEditField
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_3       matlab.ui.control.ToggleButton
        Button_2       matlab.ui.control.ToggleButton
        Button         matlab.ui.control.ToggleButton
        RightPanel     matlab.ui.container.Panel
        TiButton       matlab.ui.control.Button
        LaButton       matlab.ui.control.Button
        SolButton      matlab.ui.control.Button
        FaButton       matlab.ui.control.Button
        MiButton       matlab.ui.control.Button
        ReButton       matlab.ui.control.Button
        DoButton       matlab.ui.control.Button
        UIAxes_2       matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        % 整个示波器的状态变量
        mode = 'output_mode';
        amplitude = 10;    % 生成波形的幅值
        frequence = 100;   % 生成波形的频率
        ini_phase = 0;     % 生成波形的初相位
        samp_frec = 5120;  % 采样频率
        range  = 1;        % 窗口显示的时间长度 
        t                  % 时域图动态窗口数据数组
        y                  % 时域幅值
        f                  % 频域图频率采样点数组
        y2                 % 频域幅值
        output_amp = 20;
                        
    end
    
    methods (Access = private)
        function comp = findComponentByTag(app, tag)
        % 根据组件的Tag名称来寻找app下的对应属性，因为子属性的命名是matlab生成的，就有点局限性
            % 获取app的所有属性
            props = properties(app);
            
            % 遍历所有属性
            for i = 1:length(props)
                % 如果属性是一个UI组件并且Tag匹配，则返回该组件
                if isprop(app.(props{i}), 'Tag') && strcmp(app.(props{i}).Tag, tag)
                    comp = app.(props{i});
                    return;
                end
            end
            
            % 如果没有找到匹配的组件，则返回空
            comp = [];
        end

        function clearGraph(app)
        % 清空图表向量    
            app.t = [];
            app.y = [];
            app.f = [];
            app.y2 = [];
            app.Button_4.Value = 0;                                        % 关闭示波
        end

        function resetState(app)
        % 重置状态
            app.amplitude = 10;
            app.frequence = 100;
            app.ini_phase = 0;
            app.samp_frec = 5120;
            app.Slider.Value = 10;
            app.EditField.Value = 10;
            app.Slider_2.Value = 100;
            app.EditField_2.Value = 100;
            app.Slider_3.Value = 0;
            app.EditField_3.Value = 0;
            clearGraph(app);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % 状态选择按钮
            selectedButton = app.ButtonGroup.SelectedObject;
            app.mode = selectedButton.Tag;                                      % 切换模式
            app.DoButton.Visible = 'off';
            app.ReButton.Visible = 'off';
            app.MiButton.Visible = 'off';
            app.FaButton.Visible = 'off';
            app.SolButton.Visible = 'off';
            app.LaButton.Visible = 'off';
            app.TiButton.Visible = 'off';
            % 重置状态
            switch app.mode
                case 'output_mode'
                    
                case 'input_mode'
                    
                case 'inst_mode'
                    app.DoButton.Visible = 'on';
                    app.ReButton.Visible = 'on';
                    app.MiButton.Visible = 'on';
                    app.FaButton.Visible = 'on';
                    app.SolButton.Visible = 'on';
                    app.LaButton.Visible = 'on';
                    app.TiButton.Visible = 'on';
                        
            end

                   
        end

        % Value changed function: Slider, Slider_2, Slider_3
        function SliderValueChanged(app, event)
            % 参数滑块值输入
            tag = event.Source.Tag;
            switch tag
                case 'amp_slide'
                    app.amplitude = app.Slider.Value;
                    app.EditField.Value = app.amplitude;
                case 'fre_slide'
                    app.frequence = app.Slider_2.Value;
                    app.EditField_2.Value = app.frequence;
                case 'pha_slide'
                    app.ini_phase = app.Slider_3.Value;
                    app.EditField_3.Value = app.ini_phase;
            end
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            % 幅值键入
            value = app.EditField.Value;
            app.amplitude = value;
            app.Slider.Value = app.amplitude;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            % 波形频率键入
            value = app.EditField_2.Value;
            app.frequence = value; 
            app.Slider_2.Value = app.frequence;
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            % 初相位键入
            value = app.EditField_3.Value;
            app.ini_phase = value;
            app.Slider_3.Value = app.ini_phase;
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            % 启动/关闭 按钮
            value = app.Button_4.Value;
            deltaT = 1/app.samp_frec;
            if value                                                       % 示波器，启动！
                switch app.mode
                    case 'output_mode'                                     % 生成制定波形
                        app.t = 0: deltaT : app.range;
                        app.y = app.amplitude * sin(2*pi*app.frequence*app.t + app.ini_phase);
                        i = app.range;
                        while app.Button_4.Value
                            i = i + deltaT;
                            app.y = circshift(app.y, -1);
                            app.y(end) = app.amplitude * sin(2*pi*app.frequence*i + app.ini_phase);
                            plot(app.UIAxes_2,app.t, app.y);
                            drawnow
                            pause(deltaT);
                        end

                    case 'input_mode'

                    case 'inst_mode'                                       % 电子琴模式
                        

                end
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
        % 重置按钮
            resetState(app);
          
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            % 手动输入采样频率
            value = app.EditField_4.Value;
            app.samp_frec = value;
            clearGraph(app);
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
        % 清空图像
            clearGraph(app);
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
        % 输出声音音量大小调节
            value = app.Knob.Value;
            app.output_amp = value;
        end

        % Button pushed function: DoButton, FaButton, LaButton, MiButton, 
        % ...and 3 other components
        function DoButtonPushed(app, event)
        % 电子琴按键输入
            fre_of_sofi = [261.6, 293.6, 329.6, 349.2, 392, 440, 493.8];   % 七个唱名对应频率
            tag = event.Source.Tag;
            Fs = 81920;
            tt = 0: 1/Fs: 1;
            switch tag
                case 'do'  
                    ff = fre_of_sofi(1);
                case 're'  
                    ff = fre_of_sofi(2);
                case 'mi'  
                    ff = fre_of_sofi(3);
                case 'fa'  
                    ff = fre_of_sofi(4);
                case 'sol' 
                    ff = fre_of_sofi(5);
                case 'la'  
                    ff = fre_of_sofi(6);
                case 'ti'  
                    ff = fre_of_sofi(7);
            end
            yy = app.output_amp * sin(2*pi*ff*tt);
            sound(yy, Fs);
            plot(app.UIAxes_2,tt, yy);

        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
        % 按钮设置频率
            selectedButton = app.ButtonGroup_2.SelectedObject;
            tag = selectedButton.Tag;
            switch tag
                case 'fre_5120'
                    app.samp_frec = 5120;
                case 'fre_11025'
                    app.samp_frec = 11025;
                case 'fre_44100'
                    app.samp_frec = 44100;
            end
            clearGraph(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {757, 757};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {255, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '模式选择';
            app.ButtonGroup.Tag = 'mode_button';
            app.Button = uitogglebutton(app.ButtonGroup);
            app.Button.Tag = 'output_mode';
            app.Button.Text = '波形绘制';
            app.Button.Value = true;
            app.Button_2 = uitogglebutton(app.ButtonGroup);
            app.Button_2.Tag = 'input_mode';
            app.Button_2.Text = '波形输入';
            app.Button_3 = uitogglebutton(app.ButtonGroup);
            app.Button_3.Tag = 'inst_mode';
            app.Button_3.Text = '电子乐器';
            app.EditField = uieditfield(app.LeftPanel, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Tag = 'amp_val';
            app.EditField.Value = 10;
            app.EditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Tag = 'fre_val';
            app.EditField_2.Value = 100;
            app.EditField_3 = uieditfield(app.LeftPanel, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Tag = 'pha_val';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '幅值';
            app.Slider = uislider(app.LeftPanel);
            app.Slider.Limits = [0 2000];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Tag = 'amp_slide';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = '频率';
            app.Slider_2 = uislider(app.LeftPanel);
            app.Slider_2.Limits = [0 500];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_2.Tag = 'fre_slide';
            app.Slider_2.Value = 100;
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = '初始相位';
            app.Slider_3 = uislider(app.LeftPanel);
            app.Slider_3.Limits = [0 3.14159265358979];
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider_3.Tag = 'pha_slide';
            app.Button_4 = uibutton(app.LeftPanel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Tag = 'start_button';
            app.Button_4.Text = '  启动！';
            app.Button_5 = uibutton(app.LeftPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = 'reset_button';
            app.Button_5.Text = '重置';
            app.ButtonGroup_2 = uibuttongroup(app.LeftPanel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.Title = '采样频率';
            app.ButtonGroup_2.Tag = 'fre_selc';
            app.HzButton_3 = uitogglebutton(app.ButtonGroup_2);
            app.HzButton_3.Tag = 'frec_5120';
            app.HzButton_3.Text = '5120Hz';
            app.HzButton_3.Value = true;
            app.HzButton_2 = uitogglebutton(app.ButtonGroup_2);
            app.HzButton_2.Tag = 'frec_11025';
            app.HzButton_2.Text = '11025Hz';
            app.HzButton = uitogglebutton(app.ButtonGroup_2);
            app.HzButton.Tag = 'inst_mode';
            app.HzButton.Text = '44100Hz';
            app.EditField_4 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_4.Tag = 'frec_val';
            app.EditField_4.Value = 5120;
            app.Button_6 = uibutton(app.LeftPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = 'clear_button';
            app.Button_6.Text = '清空';
            app.Label_4 = uilabel(app.LeftPanel);
            app.Label_4.Text = '音量大小';
            app.Knob = uiknob(app.LeftPanel, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Tag = 'output_amp';
            app.Knob.Value = 20;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '频域')
            xlabel(app.UIAxes, 'f')
            ylabel(app.UIAxes, 'A')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, '时域')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'A')
            zlabel(app.UIAxes_2, 'Z')
            app.DoButton = uibutton(app.RightPanel, 'push');
            app.DoButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.DoButton.Tag = 'do';
            app.DoButton.Visible = 'off';
            app.DoButton.Text = 'Do';
            app.ReButton = uibutton(app.RightPanel, 'push');
            app.ReButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.ReButton.Tag = 're';
            app.ReButton.Visible = 'off';
            app.ReButton.Text = 'Re';
            app.MiButton = uibutton(app.RightPanel, 'push');
            app.MiButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.MiButton.Tag = 'mi';
            app.MiButton.Visible = 'off';
            app.MiButton.Text = 'Mi';
            app.FaButton = uibutton(app.RightPanel, 'push');
            app.FaButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.FaButton.Tag = 'fa';
            app.FaButton.Visible = 'off';
            app.FaButton.Text = 'Fa';
            app.SolButton = uibutton(app.RightPanel, 'push');
            app.SolButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.SolButton.Tag = 'sol';
            app.SolButton.Visible = 'off';
            app.SolButton.Text = 'Sol';
            app.LaButton = uibutton(app.RightPanel, 'push');
            app.LaButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.LaButton.Tag = 'la';
            app.LaButton.Visible = 'off';
            app.LaButton.Text = 'La';
            app.TiButton = uibutton(app.RightPanel, 'push');
            app.TiButton.ButtonPushedFcn = createCallbackFcn(app, @DoButtonPushed, true);
            app.TiButton.Tag = 'ti';
            app.TiButton.Visible = 'off';
            app.TiButton.Text = 'Ti';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oscillograph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end